classdef VAMPYRgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InfileTab                    matlab.ui.container.Tab
        ConvergenceParametersPanel   matlab.ui.container.Panel
        icbinvEditField              matlab.ui.control.NumericEditField
        icbinvEditFieldLabel         matlab.ui.control.Label
        xrsstepEditField             matlab.ui.control.NumericEditField
        xrsstepEditFieldLabel        matlab.ui.control.Label
        xrsfinEditField              matlab.ui.control.NumericEditField
        xrsfinEditFieldLabel         matlab.ui.control.Label
        xrsiniEditField              matlab.ui.control.NumericEditField
        xrsiniEditFieldLabel         matlab.ui.control.Label
        irsvarEditField              matlab.ui.control.NumericEditField
        irsvarEditFieldLabel         matlab.ui.control.Label
        itMaxSOEditField             matlab.ui.control.NumericEditField
        itMaxSOEditFieldLabel        matlab.ui.control.Label
        itMaxIEditField              matlab.ui.control.NumericEditField
        itMaxIEditFieldLabel         matlab.ui.control.Label
        itMaxEEditField              matlab.ui.control.NumericEditField
        itMaxEEditFieldLabel         matlab.ui.control.Label
        errsoEditField               matlab.ui.control.NumericEditField
        errsoEditFieldLabel          matlab.ui.control.Label
        errmEditField                matlab.ui.control.NumericEditField
        errmEditFieldLabel           matlab.ui.control.Label
        errdEditField                matlab.ui.control.NumericEditField
        errdEditFieldLabel           matlab.ui.control.Label
        errsEditField                matlab.ui.control.NumericEditField
        errsEditFieldLabel           matlab.ui.control.Label
        FileIOSettingsPanel          matlab.ui.container.Panel
        nWriteEditField              matlab.ui.control.NumericEditField
        nWriteEditFieldLabel         matlab.ui.control.Label
        iCubCompEditField            matlab.ui.control.NumericEditField
        iCubCompEditFieldLabel       matlab.ui.control.Label
        iSaveEditField               matlab.ui.control.NumericEditField
        iSaveEditFieldLabel          matlab.ui.control.Label
        iRecoverEditField            matlab.ui.control.NumericEditField
        iRecoverEditFieldLabel       matlab.ui.control.Label
        ModelingConditionsPanel      matlab.ui.container.Panel
        hardeningEditField           matlab.ui.control.NumericEditField
        hardeningEditFieldLabel      matlab.ui.control.Label
        gShapeEditField              matlab.ui.control.NumericEditField
        gShapeEditFieldLabel         matlab.ui.control.Label
        orientEditField              matlab.ui.control.NumericEditField
        orientEditFieldLabel         matlab.ui.control.Label
        iFluctEditField              matlab.ui.control.NumericEditField
        iFluctEditFieldLabel         matlab.ui.control.Label
        nNeighEditField              matlab.ui.control.NumericEditField
        nNeighEditFieldLabel         matlab.ui.control.Label
        interactionEditField         matlab.ui.control.NumericEditField
        interactionEditFieldLabel    matlab.ui.control.Label
        DeformationInformationPanel  matlab.ui.container.Panel
        nProcessEditField            matlab.ui.control.NumericEditField
        nProcessEditFieldLabel       matlab.ui.control.Label
        BasicInfoPanel               matlab.ui.container.Panel
        phaseFrac2EditField          matlab.ui.control.NumericEditField
        phaseFrac2EditFieldLabel     matlab.ui.control.Label
        phaseFrac1EditField          matlab.ui.control.NumericEditField
        phaseFrac1EditFieldLabel     matlab.ui.control.Label
        nPhaseEditField              matlab.ui.control.NumericEditField
        nPhaseEditFieldLabel         matlab.ui.control.Label
        RunVPSCButton                matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        LoadINFILEButton             matlab.ui.control.Button
        SXfilesTab                   matlab.ui.container.Tab
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        SXfilesTextArea              matlab.ui.control.TextArea
        SXfilesTextAreaLabel         matlab.ui.control.Label
        TEXfilesTab                  matlab.ui.container.Tab
        Button_4                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        TEXfilesTextArea             matlab.ui.control.TextArea
        TEXfilesTextAreaLabel        matlab.ui.control.Label
        MORPHfileTab                 matlab.ui.container.Tab
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        MORPHfilesTextArea           matlab.ui.control.TextArea
        MORPHfilesTextAreaLabel      matlab.ui.control.Label
        DEFfilesTab                  matlab.ui.container.Tab
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        DEFfilesTextArea             matlab.ui.control.TextArea
        DEFfilesTextAreaLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadINFILEButton
        function LoadINFILEButtonPushed(app, event)
            [infile,infilePath] = uigetfile('*.*','Select VPSC infile');
            if infile ~= 'vpsc7.in'
                error('That''s not an infile!')
            end
            app.EditField.Value = fullfile(infilePath,infile);
            parameters = vpscParameters; 
            parameters.fromfile(fullfile(infilePath, 'vpsc7.in'));

            % Update all the INFILE tab fields based on the infile
            app.nPhaseEditField.Value = parameters.nPhase;
            app.phaseFrac1EditField.Value = parameters.phaseFrac(1);
            app.phaseFrac2EditField.Value = parameters.phaseFrac(2);
            app.nProcessEditField.Value = parameters.nProcess; 
            app.hardeningEditField.Value = parameters.iUpdateHardening;
            app.gShapeEditField.Value = parameters.iUpdateMorph;
            app.orientEditField.Value = parameters.iUpdateOri;
            app.iFluctEditField.Value = parameters.iFluctuation;
            app.nNeighEditField.Value = parameters.nNeighbor;
            app.interactionEditField.Value = parameters.interactionType;
            app.nWriteEditField.Value = parameters.nWrite;
            app.iCubCompEditField.Value = parameters.iCubeComp;
            app.iSaveEditField.Value = parameters.iSave;
            app.iRecoverEditField.Value = parameters.iRecover;
            app.icbinvEditField.Value = parameters.iBCinv;
            app.xrsstepEditField.Value = parameters.jrstep;
            app.xrsfinEditField.Value = parameters.jrsfin;
            app.xrsiniEditField.Value = parameters.jrsini; 
            app.irsvarEditField.Value = parameters.irsvar;
            app.itMaxSOEditField.Value = parameters.itMaxInternalSO;
            app.itMaxIEditField.Value = parameters.itMaxTot;
            app.itMaxEEditField.Value = parameters.itMaxExternal; 
            app.errsoEditField.Value = parameters.errSecondOrder;
            app.errmEditField.Value = parameters.errModuli;
            app.errdEditField.Value = parameters.errStrRateD; 
            app.errsEditField.Value = parameters.errStress; 

            % Update SX file tab
            app.SXfilesTextArea.Value = parameters.fnameSX; 

            % Update TEX file tab
            app.TEXfilesTextArea.Value = parameters.fnameTEX;

            % Update MORPH file tab
            app.MORPHfilesTextArea.Value = parameters.fnameMORPH;

            % Update DEF file tab
            app.DEFfilesTextArea.Value = parameters.processDetail; 

        end

        % Button pushed function: RunVPSCButton
        function RunVPSCButtonPushed(app, event)
            parameters = vpscParameters; 
            parameters.nPhase = app.nPhaseEditField.Value;
            parameters.phaseFrac(1) = app.phaseFrac1EditField.Value;
            parameters.phaseFrac(2) = app.phaseFrac2EditField.Value;
            parameters.nProcess = app.nProcessEditField.Value;
            app.hardeningEditField.Value = parameters.iUpdateHardening;
            app.gShapeEditField.Value = parameters.iUpdateMorph;
            app.orientEditField.Value = parameters.iUpdateOri;
            app.iFluctEditField.Value = parameters.iFluctuation;
            app.nNeighEditField.Value = parameters.nNeighbor;
            app.interactionEditField.Value = parameters.interactionType;
            app.nWriteEditField.Value = parameters.nWrite;
            app.iCubCompEditField.Value = parameters.iCubeComp;
            app.iSaveEditField.Value = parameters.iSave;
            app.iRecoverEditField.Value = parameters.iRecover;
            app.icbinvEditField.Value = parameters.iBCinv;
            app.xrsstepEditField.Value = parameters.jrstep;
            app.xrsfinEditField.Value = parameters.jrsfin;
            app.xrsiniEditField.Value = parameters.jrsini; 
            app.irsvarEditField.Value = parameters.irsvar;
            app.itMaxSOEditField.Value = parameters.itMaxInternalSO;
            app.itMaxIEditField.Value = parameters.itMaxTot;
            app.itMaxEEditField.Value = parameters.itMaxExternal; 
            app.errsoEditField.Value = parameters.errSecondOrder;
            app.errmEditField.Value = parameters.errModuli;
            app.errdEditField.Value = parameters.errStrRateD; 
            app.errsEditField.Value = parameters.errStress;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InfileTab = uitab(app.TabGroup);
            app.InfileTab.Title = 'Infile';
            app.LoadINFILEButton = uibutton(app.InfileTab, 'push');
            app.LoadINFILEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadINFILEButtonPushed, true);
            app.LoadINFILEButton.Text = 'Load INFILE';
            app.EditField = uieditfield(app.InfileTab, 'text');
            app.RunVPSCButton = uibutton(app.InfileTab, 'push');
            app.RunVPSCButton.ButtonPushedFcn = createCallbackFcn(app, @RunVPSCButtonPushed, true);
            app.RunVPSCButton.Text = 'Run VPSC';
            app.BasicInfoPanel = uipanel(app.InfileTab);
            app.BasicInfoPanel.Title = 'Basic Info';
            app.nPhaseEditFieldLabel = uilabel(app.BasicInfoPanel);
            app.nPhaseEditFieldLabel.Text = 'nPhase';
            app.nPhaseEditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.nPhaseEditField.Value = 1;
            app.phaseFrac1EditFieldLabel = uilabel(app.BasicInfoPanel);
            app.phaseFrac1EditFieldLabel.Text = 'phaseFrac1';
            app.phaseFrac1EditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.phaseFrac1EditField.Value = 1;
            app.phaseFrac2EditFieldLabel = uilabel(app.BasicInfoPanel);
            app.phaseFrac2EditFieldLabel.Text = 'phaseFrac2';
            app.phaseFrac2EditField = uieditfield(app.BasicInfoPanel, 'numeric');
            app.phaseFrac2EditField.Value = 1;
            app.DeformationInformationPanel = uipanel(app.InfileTab);
            app.DeformationInformationPanel.Title = 'Deformation Information';
            app.nProcessEditFieldLabel = uilabel(app.DeformationInformationPanel);
            app.nProcessEditFieldLabel.Text = 'nProcess';
            app.nProcessEditField = uieditfield(app.DeformationInformationPanel, 'numeric');
            app.nProcessEditField.Value = 1;
            app.ModelingConditionsPanel = uipanel(app.InfileTab);
            app.ModelingConditionsPanel.Title = 'Modeling Conditions';
            app.interactionEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.interactionEditFieldLabel.Text = 'interaction';
            app.interactionEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.nNeighEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.nNeighEditFieldLabel.Text = 'nNeigh';
            app.nNeighEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.iFluctEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.iFluctEditFieldLabel.Text = 'iFluct';
            app.iFluctEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.orientEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.orientEditFieldLabel.Text = 'orient';
            app.orientEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.gShapeEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.gShapeEditFieldLabel.Text = 'gShape';
            app.gShapeEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.hardeningEditFieldLabel = uilabel(app.ModelingConditionsPanel);
            app.hardeningEditFieldLabel.Text = 'hardening';
            app.hardeningEditField = uieditfield(app.ModelingConditionsPanel, 'numeric');
            app.FileIOSettingsPanel = uipanel(app.InfileTab);
            app.FileIOSettingsPanel.Title = 'File I/O Settings';
            app.iRecoverEditFieldLabel = uilabel(app.FileIOSettingsPanel);
            app.iRecoverEditFieldLabel.Text = 'iRecover';
            app.iRecoverEditField = uieditfield(app.FileIOSettingsPanel, 'numeric');
            app.iSaveEditFieldLabel = uilabel(app.FileIOSettingsPanel);
            app.iSaveEditFieldLabel.Text = 'iSave';
            app.iSaveEditField = uieditfield(app.FileIOSettingsPanel, 'numeric');
            app.iCubCompEditFieldLabel = uilabel(app.FileIOSettingsPanel);
            app.iCubCompEditFieldLabel.Text = 'iCubComp';
            app.iCubCompEditField = uieditfield(app.FileIOSettingsPanel, 'numeric');
            app.nWriteEditFieldLabel = uilabel(app.FileIOSettingsPanel);
            app.nWriteEditFieldLabel.Text = 'nWrite';
            app.nWriteEditField = uieditfield(app.FileIOSettingsPanel, 'numeric');
            app.ConvergenceParametersPanel = uipanel(app.InfileTab);
            app.ConvergenceParametersPanel.Title = 'Convergence Parameters';
            app.errsEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.errsEditFieldLabel.Text = 'errs';
            app.errsEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.errsEditField.Value = 0.001;
            app.errdEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.errdEditFieldLabel.Text = 'errd';
            app.errdEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.errdEditField.Value = 0.001;
            app.errmEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.errmEditFieldLabel.Text = 'errm';
            app.errmEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.errmEditField.Value = 0.001;
            app.errsoEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.errsoEditFieldLabel.Text = 'errso';
            app.errsoEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.errsoEditField.Value = 0.001;
            app.itMaxEEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.itMaxEEditFieldLabel.Text = 'itMaxE';
            app.itMaxEEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.itMaxEEditField.Value = 100;
            app.itMaxIEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.itMaxIEditFieldLabel.Text = 'itMaxI';
            app.itMaxIEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.itMaxIEditField.Value = 100;
            app.itMaxSOEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.itMaxSOEditFieldLabel.Text = 'itMaxSO';
            app.itMaxSOEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.itMaxSOEditField.Value = 25;
            app.irsvarEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.irsvarEditFieldLabel.Text = 'irsvar';
            app.irsvarEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.xrsiniEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.xrsiniEditFieldLabel.Text = 'xrsini';
            app.xrsiniEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.xrsfinEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.xrsfinEditFieldLabel.Text = 'xrsfin';
            app.xrsfinEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.xrsstepEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.xrsstepEditFieldLabel.Text = 'xrsstep';
            app.xrsstepEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.icbinvEditFieldLabel = uilabel(app.ConvergenceParametersPanel);
            app.icbinvEditFieldLabel.Text = 'icbinv';
            app.icbinvEditField = uieditfield(app.ConvergenceParametersPanel, 'numeric');
            app.SXfilesTab = uitab(app.TabGroup);
            app.SXfilesTab.Title = 'SX files';
            app.SXfilesTextAreaLabel = uilabel(app.SXfilesTab);
            app.SXfilesTextAreaLabel.Text = 'SX files';
            app.SXfilesTextArea = uitextarea(app.SXfilesTab);
            app.Button = uibutton(app.SXfilesTab, 'push');
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.SXfilesTab, 'push');
            app.Button_2.Text = '-';
            app.TEXfilesTab = uitab(app.TabGroup);
            app.TEXfilesTab.Title = 'TEX files';
            app.TEXfilesTextAreaLabel = uilabel(app.TEXfilesTab);
            app.TEXfilesTextAreaLabel.Text = 'TEX files';
            app.TEXfilesTextArea = uitextarea(app.TEXfilesTab);
            app.Button_3 = uibutton(app.TEXfilesTab, 'push');
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.TEXfilesTab, 'push');
            app.Button_4.Text = '-';
            app.MORPHfileTab = uitab(app.TabGroup);
            app.MORPHfileTab.Title = 'MORPH file';
            app.MORPHfilesTextAreaLabel = uilabel(app.MORPHfileTab);
            app.MORPHfilesTextAreaLabel.Text = 'MORPH files';
            app.MORPHfilesTextArea = uitextarea(app.MORPHfileTab);
            app.Button_5 = uibutton(app.MORPHfileTab, 'push');
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.MORPHfileTab, 'push');
            app.Button_6.Text = '-';
            app.DEFfilesTab = uitab(app.TabGroup);
            app.DEFfilesTab.Title = 'DEF files';
            app.DEFfilesTextAreaLabel = uilabel(app.DEFfilesTab);
            app.DEFfilesTextAreaLabel.Text = 'DEF files';
            app.DEFfilesTextArea = uitextarea(app.DEFfilesTab);
            app.Button_7 = uibutton(app.DEFfilesTab, 'push');
            app.Button_7.Text = '+';
            app.Button_8 = uibutton(app.DEFfilesTab, 'push');
            app.Button_8.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VAMPYRgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end